classdef EasyNIRSv10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TOGGLESTIMULUSButton     matlab.ui.control.StateButton
        TrialNotesTextArea       matlab.ui.control.TextArea
        TrialNotesTextAreaLabel  matlab.ui.control.Label
        STOPButton               matlab.ui.control.Button
        CONNECTButton            matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = public)
        continuePlotting double = 0; % Description
        globalIndex = 0;
        Timer;
        
    end
    
   % Add dataQueue as a property of the app
properties (Access = private)
    dataQueue % Data queue for storing the fNIRS data
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
    channelSources = [1, 1, 1, 1, 1, ...
                      2, 2, 2, 2, 2, ...
                      3, 3, 3, 3, 3, ...
                      4, 4, 4, 4, 4, ...
                      5, 5, 5, 5, 5, ...
                      6, 6, 6, 6, 6, ...
                      7, 7, 7, 7, 7, ...
                      8, 8, 8, 8, 8];

    channelDetectors = [1, 3, 7, 11, 13, ...
                        1, 3, 4, 8, 14, ...
                        2, 5, 6, 9, 15, ...
                        2, 6, 10, 12, 16, ...
                        3, 7, 8, 13, 14, ...
                        5, 8, 4, 14, 23, ...
                        5, 9, 15, 23, 23, ...
                        9, 6, 10, 15, 16];

    app.continuePlotting = 1;
    clear fNIRS_Device
    fNIRS_Device = ble("BBOL fNIRS x0015");
    dataReadingService = "938548E6-C655-11EA-87D0-0242AC130003";
    ledService = "19B10000-E8F2-537E-4F6C-D104768A1214";

    deviceData = characteristic(fNIRS_Device, dataReadingService, "77539407-6493-4B89-985F-BAAF4C0F8D86");
    controller = characteristic(fNIRS_Device, ledService, "19B10001-E8F2-537E-4F6C-D104768A1213");

    write(controller, 1);

    app.dataQueue = zeros(100000, 194); % Assuming 194 is the length of data + 1 for elapsedTime
    i = 1;
    elapsedTimeTimer = tic;

    % Create and start a timer for updating the button text
    app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 1, ...
                      'TimerFcn', @(~, ~) updateButtonText(app, elapsedTimeTimer));
    start(app.Timer);

    while app.continuePlotting
        data = read(deviceData);  
        elapsedTime = toc(elapsedTimeTimer);
        newRow = [elapsedTime, app.TOGGLESTIMULUSButton.Value, data];
        app.dataQueue(i, :) = newRow;
        i = i + 1;
        app.globalIndex = i;

        % Reduce frequency of drawnow to a custom interval
        if mod(i, 4) == 0  
            drawnow limitrate;
        end
    end

    % Stop the timer and clean up memory
    stop(app.Timer);
    delete(app.Timer);

    % Remove unused rows from dataQueue
    app.dataQueue = app.dataQueue(1:i-1, :);
    headers = cell(1, 194);
    headers{1} = 'Time';
    headers{2} = 'Stimulus';
    for j = 1:length(channelSources)
        colIndex1 = 3 + (j - 1) * 4;
        colIndex2 = colIndex1 + 2;
        headers{colIndex1} = ['S' num2str(channelSources(j)) '_D' num2str(channelDetectors(j)) '_740nm'];
        headers{colIndex2} = ['S' num2str(channelSources(j)) '_D' num2str(channelDetectors(j)) '_850nm'];
    end

    % Prompt the user to specify the file name and location for Excel
    [file, path] = uiputfile('*.xlsx', 'Save fNIRS Data As');
    if ischar(file)
        filename = fullfile(path, file);
        % Save the headers and data to the specified file
        writecell(headers, filename, 'WriteMode', 'overwritesheet');
        writematrix(app.dataQueue, filename, 'WriteMode', 'append');
        disp(['Data saved to ', filename]);
        
        % Prompt the user to specify the file name and location for text notes
        [txtFile, txtPath] = uiputfile('*.txt', 'Save Trial Notes As');
        if ischar(txtFile)
            txtFilename = fullfile(txtPath, txtFile);
            value = app.TrialNotesTextArea.Value;
            f = fopen(txtFilename, 'w');
            formatSpec = "%s";  
            for i = 1:length(value)
                fprintf(f, formatSpec, value{i});
            end
            fclose(f);  % Close the file after writing
            disp(['Trial notes saved to ', txtFilename]);
        else
            disp('File save cancelled.');
        end
    else
        disp('File save cancelled.');
    end
end

function updateButtonText(app, elapsedTimeTimer)
    elapsedTime = toc(elapsedTimeTimer);
    app.CONNECTButton.Text = sprintf('Time: %.2f s', elapsedTime);


        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.continuePlotting = 0;
        end

        % Value changed function: TrialNotesTextArea
        function TrialNotesTextAreaValueChanged(app, event)
            value = app.TrialNotesTextArea.Value;
            f=fopen('trialnotes.txt','w');
            formatSpec= "%s";  
            for i =1:length(value)
                fprintf(f,formatSpec,value{i});
            end
        end

        % Button pushed function: Button
        function S1_D1_Button(app, event)
            fig = uifigure('Name', 'S1_D1_Channel', 'NumberTitle', 'off');
            ax = uiaxes(fig, 'Position', [75, 75, 300, 300]);
            ax.Title.String = 'Dynamic Data Plot';
            ax.XLabel.String = 'Time (s)';
            ax.YLabel.String = 'Voltage (V)';
        
            % Initialize animated lines for dynamic data plotting
            lineHandle1 = animatedline(ax, 'DisplayName', '740nm', 'Color', 'b');
            hold(ax, 'on');
            lineHandle2 = animatedline(ax, 'DisplayName', '850nm', 'Color', 'r');
            hold(ax, 'off');
            legend(ax, 'show');
        
            ax.XLim = [0, 30];
            ax.YLim = [0, 3.3];
        
            % Timer to update the plot
            updateTimer = timer('ExecutionMode', 'fixedRate', 'Period', 5, 'TimerFcn', @(~,~) updatePlot(app, lineHandle1, lineHandle2, ax));
            start(updateTimer);
        
            function updatePlot(app, lineHandle1, lineHandle2, ax)
                if app.globalIndex > 1
                    % Extract new data since the last update
                    startIdx = max(1, app.globalIndex - round(2.5 * 10)); % Assuming 10 samples per second
                    newData = app.dataQueue(startIdx:app.globalIndex - 1, :);
        
                    % Iterate through new data and add points to the animated lines
                    for j = 1:size(newData, 1)
                        elapsedTime = newData(j, 1);
                        valueData1 = newData(j, 3) * 3 / 248.8; % 740nm data
                        valueData2 = newData(j, 5) * 3 / 248.8; % 850nm data
                        addpoints(lineHandle1, elapsedTime, valueData1);
                        addpoints(lineHandle2, elapsedTime, valueData2);
                    end
        
                    if elapsedTime > 30
                        ax.XLim = [elapsedTime - 30, elapsedTime];
                    else
                        ax.XLim = [0, 30];
                    end
                    drawnow limitrate;
                end
            end


        end

        % Button pushed function: Button_2
        function S1_D3_Button(app, event)
            fig = uifigure('Name', 'S1_D3_Channel', 'NumberTitle', 'off');
            ax = uiaxes(fig, 'Position', [75, 75, 300, 300]);
            ax.Title.String = 'Dynamic Data Plot';
            ax.XLabel.String = 'Time (s)';
            ax.YLabel.String = 'Voltage (V)';
        
            % Initialize animated lines for dynamic data plotting
            lineHandle1 = animatedline(ax, 'DisplayName', '740nm', 'Color', 'b');
            hold(ax, 'on');
            lineHandle2 = animatedline(ax, 'DisplayName', '850nm', 'Color', 'r');
            hold(ax, 'off');
            legend(ax, 'show');
        
            ax.XLim = [0, 30];
            ax.YLim = [0, 3.3];
        
            % Timer to update the plot
            updateTimer = timer('ExecutionMode', 'fixedRate', 'Period', 5, 'TimerFcn', @(~,~) updatePlot(app, lineHandle1, lineHandle2, ax));
            start(updateTimer);
        
            function updatePlot(app, lineHandle1, lineHandle2, ax)
                if app.globalIndex > 1
                    % Extract new data since the last update
                    startIdx = max(1, app.globalIndex - round(2.5 * 10)); % Assuming 10 samples per second
                    newData = app.dataQueue(startIdx:app.globalIndex - 1, :);
        
                    % Iterate through new data and add points to the animated lines
                    for j = 1:size(newData, 1)
                        elapsedTime = newData(j, 1);
                        valueData1 = newData(j, 7) * 3 / 248.8; % 740nm data
                        valueData2 = newData(j, 9) * 3 / 248.8; % 850nm data
                        addpoints(lineHandle1, elapsedTime, valueData1);
                        addpoints(lineHandle2, elapsedTime, valueData2);
                    end
        
                    % Adjust x-axis limits dynamically
                    if elapsedTime > 30
                        ax.XLim = [elapsedTime - 30, elapsedTime];
                    else
                        ax.XLim = [0, 30];
                    end
        
                    % Update the plot
                    drawnow limitrate;
                end
            end


        end

        % Value changed function: TOGGLESTIMULUSButton
        function TOGGLESTIMULUSButtonValueChanged(app, event)
        
            currentColor = app.TOGGLESTIMULUSButton.BackgroundColor;
            
            % Define the colors
            white = [1, 1, 1];
            cyan = [0, 1, 1];
            
            % Toggle the button's color and update the value
            if isequal(currentColor, white)
                app.TOGGLESTIMULUSButton.BackgroundColor = cyan;
                app.TOGGLESTIMULUSButton.Value = 1;  
            else
                app.TOGGLESTIMULUSButton.BackgroundColor = white;
                app.TOGGLESTIMULUSButton.Value = 0;  
            end
        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'head diagram.png');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @S1_D1_Button, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @S1_D3_Button, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '';
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.TrialNotesTextAreaLabel = uilabel(app.UIFigure);
            app.TrialNotesTextAreaLabel.Text = 'Trial Notes';
            app.TrialNotesTextArea = uitextarea(app.UIFigure);
            app.TrialNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @TrialNotesTextAreaValueChanged, true);
            app.TOGGLESTIMULUSButton = uibutton(app.UIFigure, 'state');
            app.TOGGLESTIMULUSButton.ValueChangedFcn = createCallbackFcn(app, @TOGGLESTIMULUSButtonValueChanged, true);
            app.TOGGLESTIMULUSButton.Text = 'TOGGLE STIMULUS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EasyNIRSv10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end